classdef RobesonPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RebesonPlotUIFigure   matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        GasPairDropDownLabel  matlab.ui.control.Label
        GasPairDropDown       matlab.ui.control.DropDown
        Rebeson1991Label      matlab.ui.control.Label
        Rebeson2008Label      matlab.ui.control.Label
    end

    
    properties (Access = private)
        X3 % Description
        Y3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, selectivity, permeability)
            app.X3 = permeability;
            app.Y3 = selectivity;
            
            k1 = 1073700; 
            n1 = -2.6264;
            k2 = 5369140;
            n2 = -2.636;
            
            Pi = [1,10000];
            alpha1 = [(Pi(1)/k1)^(1/n1),(Pi(2)/k1)^(1/n1)];
            alpha2 = [(Pi(1)/k2)^(1/n2),(Pi(2)/k2)^(1/n2)];

            loglog(app.UIAxes,Pi,alpha1,'-b',Pi,alpha2,'-r',app.X3,app.Y3,'--ko','LineWidth',2,'MarkerEdgeColor','k','MarkerFaceColor',[.49 1 .63],'MarkerSize',10);
            app.UIAxes.XLim = [0,10000];
            app.UIAxes.YLim = [0,inf];
            
        end

        % Value changed function: GasPairDropDown
        function GasPairDropDownValueChanged(app, event)
            value = app.GasPairDropDown.Value;
            switch value
                case "Choose"
                    k1 = 1; 
                    n1 = 1;
                    k2 = 1;
                    n2 = 1;
                case "CO2/ CH4"
                    k1 = 1073700; 
                    n1 = -2.6264;
                    k2 = 5369140;
                    n2 = -2.636;
                case "O2/ N2"
                    k1 = 389224; 
                    n1 = -5.8;
                    k2 = 1396000;
                    n2 = -5.666;
                case "H2/ CH4"
                    k1 = 18500; 
                    n1 = -1.2112;
                    k2 = 27200;
                    n2 = -1.017;
            end
            Pi = [1,10000];
            alpha1 = [(Pi(1)/k1)^(1/n1),(Pi(2)/k1)^(1/n1)];
            alpha2 = [(Pi(1)/k2)^(1/n2),(Pi(2)/k2)^(1/n2)];

            loglog(app.UIAxes,Pi,alpha1,'-b',Pi,alpha2,'-r',app.X3,app.Y3,'--ko','LineWidth',2,'MarkerEdgeColor','k','MarkerFaceColor',[.49 1 .63],'MarkerSize',10);
            app.UIAxes.XLim = [0,10000];
            app.UIAxes.YLim = [0,inf];
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RebesonPlotUIFigure = uifigure('Visible', 'off');
            app.RebesonPlotUIFigure.Name = 'Rebeson Plot';
            app.UIAxes = uiaxes(app.RebesonPlotUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Permeability (Barrer)')
            ylabel(app.UIAxes, 'Selectivity')
            app.GasPairDropDownLabel = uilabel(app.RebesonPlotUIFigure);
            app.GasPairDropDownLabel.Text = 'Gas Pair';
            app.GasPairDropDown = uidropdown(app.RebesonPlotUIFigure);
            app.GasPairDropDown.Items = {'CO2/ CH4', 'O2/ N2', 'H2/ CH4'};
            app.GasPairDropDown.ValueChangedFcn = createCallbackFcn(app, @GasPairDropDownValueChanged, true);
            app.GasPairDropDown.Value = 'CO2/ CH4';
            app.Rebeson1991Label = uilabel(app.RebesonPlotUIFigure);
            app.Rebeson1991Label.Text = {'――――――― Rebeson (1991)'; ''};
            app.Rebeson2008Label = uilabel(app.RebesonPlotUIFigure);
            app.Rebeson2008Label.Text = {'――――――― Rebeson (2008)'; ''};
            % Show the figure after all components are created
            app.RebesonPlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobesonPlot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RebesonPlotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RebesonPlotUIFigure)
        end
    end
end