classdef memcal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MembraneCascadeSimulationUIFigure  matlab.ui.Figure
        OptionsMenu                 matlab.ui.container.Menu
        RobesonPlotMenu             matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        MembraneParametersPanel     matlab.ui.container.Panel
        UITableMembraneParams       matlab.ui.control.Table
        UITableInitialParams        matlab.ui.control.Table
        EconomyValuePanel           matlab.ui.container.Panel
        UITableEconomy              matlab.ui.control.Table
        StreamTablePanel            matlab.ui.container.Panel
        UITableSTream               matlab.ui.control.Table
        MembranePerformancePanel    matlab.ui.container.Panel
        UITablePerformance          matlab.ui.control.Table
        LabelResultEquil            matlab.ui.control.Label
        LabelResult                 matlab.ui.control.Label
        CALCULATEButton             matlab.ui.control.Button
        MaxIterationEditFieldLabel  matlab.ui.control.Label
        MaxIterationEditField       matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel     matlab.ui.control.Label
        ToleranceEditField          matlab.ui.control.NumericEditField
        ProductatDropDownLabel      matlab.ui.control.Label
        ProductatDropDown           matlab.ui.control.DropDown
        FeedConditionPanel          matlab.ui.container.Panel
        UITableFeed                 matlab.ui.control.Table
        EconomyEvaluationLabel      matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MembraneParams % Description
        InitialParams
        FeedParams % Description
        EcoParams
        Performance
        nS
        nE
        alpha
        CompressorPressure
        DialogApp
        X3
        Y3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % I have no idea why ?
                       
            app.MembraneParams = table("Feed Stage",8.5,15,0.5,"Unknown");
            app.UITableMembraneParams.Data = app.MembraneParams;
            
            app.InitialParams = table([8.5,15,0,0]',[" ","bar"," "," "]');
            app.UITableInitialParams.Data = app.InitialParams;
            app.UITableInitialParams.RowName = {'Selectivity','Compressor Pressure','Number of Stripping Stage', 'Number of Enriching Stage'};
            
            app.FeedParams = table([1,1,0.5,0.5,3900,150,100]',["mol/s","bar"," ", " ","Barrer","nm","%"]');
            app.UITableFeed.Data = app.FeedParams;
            app.UITableFeed.RowName = {'Molar Flow rate','Feed Pressure','Gas A (More Permeable)', 'Gas B (Less Permeable)','Permeability','Selective Layer Thickness','Compressor Efficiency'};
            
            app.EcoParams = table([0.09,100,3]',["€/kWh","€/m2","year(s)"]');
            app.UITableEconomy.Data = app.EcoParams;
            app.UITableEconomy.RowName = {'Electrical Energy Unit Cost','Membrane Module Unit Cost','Year of Operation'};
            
            app.Performance = table([0,0,0,0,0,0,0]',["%","%","%","m2","kW"," ","Barrer"]');
            app.UITablePerformance.Data = app.Performance;
            app.UITablePerformance.RowName = {'Purity','Recovery','Recycle','Area','Energy Consumption','Equivalent Selectivity','Equivalent Permeability'};
            
            
            % Fix Initial Error
            app.InitialParams = app.UITableInitialParams.Data;
            param_InitialParams = [app.InitialParams.Var1];
            app.alpha = param_InitialParams(1);
            app.CompressorPressure = param_InitialParams(2);
            app.nS = param_InitialParams(3);
            app.nE = param_InitialParams(4);
            
            % Remove title, axis labels, and tick labels
            axis(app.UIAxes,'off')
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            % Draw the PDF initially
            drawpfd(0,0,0);
            
            % Display image and stretch to fill axes
            I = imshow('img/processflowdiagram.png', 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
        end

        % Cell edit callback: UITableInitialParams
        function UITableInitialParamsCellEdit(app, event)
           
           % Collect Data from table
           app.InitialParams = app.UITableInitialParams.Data;
           param_InitialParams = [app.InitialParams.Var1];
           app.alpha = param_InitialParams(1);
           app.CompressorPressure = param_InitialParams(2);
           app.nS = param_InitialParams(3);
           app.nE = param_InitialParams(4);
           
           % Put it on Membrane parameter
           StageName(1) = "Feed Stage";
           alpha_M(1) = app.alpha;
           phi_M(1) = app.CompressorPressure^(1/(1+app.nE));
           theta_M(1) = 0.5;
           Area_M(1) = "Unknown";

           for i = 1:app.nS
               StageName(i+1) = "Stripping Stage "+num2str(i);
               alpha_M(i+1) = app.alpha;
               phi_M(i+1) = app.CompressorPressure;
               theta_M(i+1) = 0.5;
               Area_M(i+1) = "Unknown";
               
           end


           for i = 1:app.nE
               StageName(i+1+app.nS) = "Enriching Stage "+num2str(i);
               alpha_M(i+1+app.nS) = app.alpha;
               phi_M(i+1+app.nS) = app.CompressorPressure^(1/(1+app.nE));
               theta_M(i+1+app.nS) = 0.5;
               Area_M(i+1+app.nS) = "Unknown";
           end
           
           app.MembraneParams = table(StageName',alpha_M',phi_M',theta_M',Area_M');
           app.UITableMembraneParams.Data = app.MembraneParams;
           
            % Draw the PDF
            drawpfd(app.nS,app.nE,0);
            
            % Display image and stretch to fill axes
            I = imshow('img/processflowdiagram.png', 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            % Data from Table
            app.MembraneParams = app.UITableMembraneParams.Data;
            phi = app.MembraneParams.Var3;
            theta = app.MembraneParams.Var4;
            
            phi_F = phi(1);
            theta_F = theta(1);
            
            if app.nS ~=0
                phi_S = phi(2:1+app.nS);
                theta_S = theta(2:1+app.nS);
            else
                phi_S = 1;
                theta_S = 1;
            end
            
            if app.nE ~=0
                phi_E = phi(2+app.nS:1+app.nS+app.nE);
                theta_E = theta(2+app.nS:1+app.nS+app.nE);
            else
                phi_E = 1;
                theta_E = 1;
            end
            
            app.FeedParams = app.UITableFeed.Data;
            Fparams = app.FeedParams.Var1;
            Feed = Stream(Fparams(1),Fparams(2),Fparams(3),Fparams(4));
            Permeance = (Fparams(5) * 3.35e-16)/(Fparams(6) * 1e-9); % From Barrer to mol/m2.s.Pa
            EffComp = Fparams(7);
            Fparams(3:4) = [Feed.xA,Feed.xB];
            app.FeedParams.Var1 = Fparams;
            app.UITableFeed.Data = app.FeedParams;
            
            % Simulation
            maxint = app.MaxIterationEditField.Value;
            crit = app.ToleranceEditField.Value;
            f = waitbar(0,'Calculating the membrane cascade ...');
           
            [StreamTable,Enriched_Product,Depleted_Product,TotalArea,esp,elops] = SingleCompressor(Feed,app.nS,app.nE,app.alpha,phi_F,theta_F,phi_E,theta_E,phi_S,theta_S,app.CompressorPressure,maxint,crit,Permeance);
            
            if elops < app.MaxIterationEditField.Value
                app.LabelResult.Text = ['Simulation converges at ',num2str(esp),' after ',num2str(elops),' iterations'];
            else
                app.LabelResult.Text = 'Simulation does not converge';
            end
            waitbar(0.25,f,'Updating Stream Table ...');
            % Result
            app.UITableSTream.Data = struct2table(StreamTable);
            waitbar(0.5,f,'Updating result ...');
            if strcmp(app.ProductatDropDown.Value,'Permeate')
                Purity = Enriched_Product.xA*100;
                Recovery = (Enriched_Product.xA*Enriched_Product.Flowrate)/(Feed.xA*Feed.Flowrate)*100;
            elseif strcmp(app.ProductatDropDown.Value,'Retentate')
                Purity = Depleted_Product.xB*100;
                Recovery = (Depleted_Product.xB*Depleted_Product.Flowrate)/(Feed.xB*Feed.Flowrate)*100;
            end
            
            Q = StreamTable.Flowrate;
            Recycle = (Q(2)-Q(1))/Q(1) * 100;
            Area = sum(TotalArea);
            
            % Compressor Energy Calculator
            Ws = Membr.DutyComp(Q(2),app.CompressorPressure)/(EffComp/100);
            
            % Roberson Plot
            waitbar(0.75,f,'Calculating single stage equivalent membrane...');
            [newAlpha,newPi]= EquilvalentStage(Enriched_Product,Depleted_Product,app.CompressorPressure,Area,app.alpha);
            Equil_alpha = newAlpha;
            Equil_permeability = newPi * Fparams(6) * 1e-9 / 3.35e-16 ;
            app.Y3 = [app.alpha , Equil_alpha];
            app.X3 = [Fparams(5) , Equil_permeability];

            
            app.Performance.Var1 = [Purity,Recovery,Recycle,Area,Ws,newAlpha,Equil_permeability]';
            app.UITablePerformance.Data = app.Performance;  
            
            % Area Estimation => Display Area on the membrane properties
            % table
            app.MembraneParams.Var5 = TotalArea';
            app.UITableMembraneParams.Data = app.MembraneParams;
            
            % Economy
            app.EcoParams = app.UITableEconomy.Data;
            Eco = app.EcoParams.Var1;
            energy_cost = Eco(1);
            membrane_cost = Eco(2);
            time = Eco(3);
            QF = Q(1);
            FinalCost = CostEstimate(QF,Area,Ws,time,membrane_cost,energy_cost);
            app.EconomyEvaluationLabel.Text = ['Economy Evaluation: ',num2str(FinalCost),' €/Nm3'];
            
            waitbar(1,f,'Finish ...');
            close(f);
            
            % Draw the PDF
            drawpfd(app.nS,app.nE,Ws);
            
            % Display image and stretch to fill axes
            I = imshow('img/processflowdiagram.png', 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Menu selected function: RobesonPlotMenu
        function RobesonPlotMenuSelected(app, event)
            app.DialogApp = RobesonPlot(app,app.Y3,app.X3);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about
        end

        % Cell edit callback: UITableFeed
        function UITableFeedCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MembraneCascadeSimulationUIFigure = uifigure('Visible', 'off');
            app.MembraneCascadeSimulationUIFigure.Name = 'Membrane Cascade Simulation';
            app.OptionsMenu = uimenu(app.MembraneCascadeSimulationUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.RobesonPlotMenu = uimenu(app.OptionsMenu);
            app.RobesonPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @RobesonPlotMenuSelected, true);
            app.RobesonPlotMenu.Text = 'Robeson Plot';
            app.AboutMenu = uimenu(app.MembraneCascadeSimulationUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About ...';
            app.MembraneParametersPanel = uipanel(app.MembraneCascadeSimulationUIFigure);
            app.MembraneParametersPanel.Title = 'Membrane Parameters';
            app.UITableMembraneParams = uitable(app.MembraneParametersPanel);
            app.UITableMembraneParams.ColumnName = {'Stage'; 'Selectivity'; 'Pressure Ratio'; 'Stage Cut'; 'Area (m2)'};
            app.UITableMembraneParams.RowName = {};
            app.UITableMembraneParams.ColumnEditable = [false false true true];
            app.UITableInitialParams = uitable(app.MembraneParametersPanel);
            app.UITableInitialParams.ColumnName = {'Value'; 'Unit'};
            app.UITableInitialParams.RowName = {};
            app.UITableInitialParams.ColumnEditable = [true false];
            app.UITableInitialParams.CellEditCallback = createCallbackFcn(app, @UITableInitialParamsCellEdit, true);
            app.EconomyValuePanel = uipanel(app.MembraneCascadeSimulationUIFigure);
            app.EconomyValuePanel.Title = 'Economy Value';
            app.UITableEconomy = uitable(app.EconomyValuePanel);
            app.UITableEconomy.ColumnName = {'Value'; 'Unit'};
            app.UITableEconomy.RowName = {};
            app.UITableEconomy.ColumnEditable = true;
            app.StreamTablePanel = uipanel(app.MembraneCascadeSimulationUIFigure);
            app.StreamTablePanel.Title = 'Stream Table';
            app.UITableSTream = uitable(app.StreamTablePanel);
            app.UITableSTream.ColumnName = {'Stream'; 'Flow rate (mol/s)'; 'Pressure (bar)'; 'xA'; 'xB'};
            app.UITableSTream.RowName = {};
            app.MembranePerformancePanel = uipanel(app.MembraneCascadeSimulationUIFigure);
            app.MembranePerformancePanel.Title = 'Membrane Performance';
            app.UITablePerformance = uitable(app.MembranePerformancePanel);
            app.UITablePerformance.ColumnName = {'Value'; 'Unit'};
            app.UITablePerformance.RowName = {};
            app.LabelResultEquil = uilabel(app.MembranePerformancePanel);
            app.LabelResultEquil.Text = '';
            app.LabelResult = uilabel(app.MembraneCascadeSimulationUIFigure);
            app.LabelResult.Text = ' ';
            app.CALCULATEButton = uibutton(app.MembraneCascadeSimulationUIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = {'CALCULATE'; ''};
            app.MaxIterationEditFieldLabel = uilabel(app.MembraneCascadeSimulationUIFigure);
            app.MaxIterationEditFieldLabel.Text = 'Max Iteration';
            app.MaxIterationEditField = uieditfield(app.MembraneCascadeSimulationUIFigure, 'numeric');
            app.MaxIterationEditField.Limits = [100 10000];
            app.MaxIterationEditField.Value = 1000;
            app.ToleranceEditFieldLabel = uilabel(app.MembraneCascadeSimulationUIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.MembraneCascadeSimulationUIFigure, 'numeric');
            app.ToleranceEditField.Limits = [1e-12 1e-06];
            app.ToleranceEditField.Value = 1e-06;
            app.ProductatDropDownLabel = uilabel(app.MembraneCascadeSimulationUIFigure);
            app.ProductatDropDownLabel.Text = 'Product at';
            app.ProductatDropDown = uidropdown(app.MembraneCascadeSimulationUIFigure);
            app.ProductatDropDown.Items = {'Permeate', 'Retentate'};
            app.ProductatDropDown.Value = 'Permeate';
            app.FeedConditionPanel = uipanel(app.MembraneCascadeSimulationUIFigure);
            app.FeedConditionPanel.Title = 'Feed Condition';
            app.UITableFeed = uitable(app.FeedConditionPanel);
            app.UITableFeed.ColumnName = {'Value'; 'Unit'};
            app.UITableFeed.RowName = {};
            app.UITableFeed.ColumnEditable = [true false];
            app.UITableFeed.CellEditCallback = createCallbackFcn(app, @UITableFeedCellEdit, true);
            app.EconomyEvaluationLabel = uilabel(app.MembraneCascadeSimulationUIFigure);
            app.EconomyEvaluationLabel.Text = '';
            app.UIAxes = uiaxes(app.MembraneCascadeSimulationUIFigure);
            % Show the figure after all components are created
            app.MembraneCascadeSimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = memcal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MembraneCascadeSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MembraneCascadeSimulationUIFigure)
        end
    end
end