classdef memoptim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FeedandrequirementPanel       matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        xFEditFieldLabel              matlab.ui.control.Label
        xFEditField                   matlab.ui.control.NumericEditField
        yPEditFieldLabel              matlab.ui.control.Label
        yPEditField                   matlab.ui.control.NumericEditField
        xREditFieldLabel              matlab.ui.control.Label
        xREditField                   matlab.ui.control.NumericEditField
        Nm3hLabel                     matlab.ui.control.Label
        OptimizationParameterPanel    matlab.ui.container.Panel
        MaxPressureEditFieldLabel     matlab.ui.control.Label
        MaxPressureEditField          matlab.ui.control.NumericEditField
        MaxStageNumberEditFieldLabel  matlab.ui.control.Label
        MaxStageNumberEditField       matlab.ui.control.NumericEditField
        SingleStageLabel              matlab.ui.control.Label
        kEditFieldLabel               matlab.ui.control.Label
        kEditField                    matlab.ui.control.NumericEditField
        nEditField_2Label             matlab.ui.control.Label
        nEditField                    matlab.ui.control.NumericEditField
        MultistageLabel               matlab.ui.control.Label
        SelectivityEditFieldLabel     matlab.ui.control.Label
        SelectivityEditField          matlab.ui.control.NumericEditField
        PermeabilitybarrerEditFieldLabel  matlab.ui.control.Label
        PermeabilitybarrerEditField   matlab.ui.control.NumericEditField
        Image2                        matlab.ui.control.Image
        ProgressPanel                 matlab.ui.container.Panel
        configLabel                   matlab.ui.control.Label
        RunOptimizationButton         matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        CaseNameEditFieldLabel        matlab.ui.control.Label
        CaseNameEditField             matlab.ui.control.EditField
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        ExportDataMenu                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        MaxStages % Description
        MaxP
        n
        k
        alpha
        Permeability
        xF
        xR
        yP
        combinations
        finalTable % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MaxStages = app.MaxStageNumberEditField.Value;
                        
            %MODIFY TABLE
            app.combinations = ListStage(app.MaxStages);
            ResultTable = zeros(length(app.combinations)+1,10 + app.MaxStages);
            app.finalTable = [[[0,0];app.combinations],ResultTable];
            titleFiller = cell(1,app.MaxStages);
            
            for i = 1:app.MaxStages
                titleFiller{i} = ['param ',num2str(i)];
            end
            
            
            app.UITable.Data = array2table(app.finalTable);
            app.UITable.ColumnName = [{'nS'},{'nE'},{'xR'},{'yP'},{'Area (m2)'},{'Duty (kW)'},{'Membrane Cost (cent EUR/Nm3)'},{'Energy Cost (cent EUR/Nm3)'},{'Compressor Cost (cent EUR/Nm3)'},{'Treatment Cost (cent EUR/Nm3)'},{'Pressure (bar)'},{'theta_F'},titleFiller(:)'];
                        

        end

        % Value changed function: MaxStageNumberEditField
        function MaxStageNumberEditFieldValueChanged(app, event)
            app.MaxStages = app.MaxStageNumberEditField.Value;
            app.combinations = ListStage(app.MaxStages);
            app.combinations = ListStage(app.MaxStages);
            ResultTable = zeros(length(app.combinations)+1,10 + app.MaxStages);
            app.finalTable = [[[0,0];app.combinations],ResultTable];
            titleFiller = cell(1,app.MaxStages);
            
            for i = 1:app.MaxStages
                titleFiller{i} = ['param ',num2str(i)];
            end
            
            
            app.UITable.Data = array2table(app.finalTable);
            app.UITable.ColumnName = [{'nS'},{'nE'},{'xR'},{'yP'},{'Area (m2)'},{'Duty (kW)'},{'Membrane Cost (cent EUR/Nm3)'},{'Energy Cost (cent EUR/Nm3)'},{'Compressor Cost (cent EUR/Nm3)'},{'Treatment Cost (cent EUR/Nm3)'},{'Pressure (bar)'},{'theta_F'},titleFiller(:)'];
                        
        end

        % Button pushed function: RunOptimizationButton
        function RunOptimizationButtonPushed(app, event)
            % TAKE THE DATA FROM GUIDE
            app.MaxStages = app.MaxStageNumberEditField.Value;
            app.n = app.nEditField.Value;
            app.k = app.kEditField.Value;
            app.alpha = app.SelectivityEditField.Value;
            app.Permeability = app.PermeabilitybarrerEditField.Value;
            app.xF = app.xFEditField.Value;
            app.xR = app.xREditField.Value;
            app.yP = app.yPEditField.Value;
            
            
            % RESHAPE TABLE
            l = length(app.combinations);
            
            % =============================================================
            % Change button name to "Processing"
            app.RunOptimizationButton.Text = 'Processing...';
            % Put text on top of icon
            app.RunOptimizationButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.RunOptimizationButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.RunOptimizationButton.Icon = wbar;
            % =============================================================
            % SINGLE STAGE OPTIMIZATION
                    
            %____________SSOPTIMIZATION HERE_______________________________
            
            %SSproducts = SSOptimization(app.k,app.n,app.xF,app.xR,app.yP)
            SSproducts = SSOptimization(app.k,app.n,app.xF,app.xR,app.yP);
            app.finalTable(1,3:2+length(SSproducts)) = SSproducts;
            close all
            %______________________________________________________________
            
            app.configLabel.Text = 'f1s0e0';
            currentProg = min(round((size(wbar,2)-2)*(1/(l+1)),size(wbar,2)-2));
            app.RunOptimizationButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
            app.RunOptimizationButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
            app.RunOptimizationButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
            pause(1)
            % =============================================================
            % MULTISTAGE OPTIMIZATION
            for i = 1:l
            nSnE = app.combinations(i,:);
            app.configLabel.Text = ['f1','s',num2str(nSnE(1)),'e',num2str(nSnE(2))];
            
            % =============================================================
            
            % MY LOOP HERE
            
            nSnE = app.combinations(i,:);
            MSproducts = MSOptimization(nSnE,app.alpha,app.Permeability,app.xF,app.xR,app.yP);
            app.finalTable(i+1,3:2+length(MSproducts)) = MSproducts;
            close all
                    
            % END MY LOOP HERE
            
            % =============================================================
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*((i+1)/(l+1)),size(wbar,2)-2));
            app.RunOptimizationButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
            app.RunOptimizationButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
            app.RunOptimizationButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
            % Pause to slow down animation
            pause(1)
            end
            % remove waitbar
            app.RunOptimizationButton.Icon = '';
            % Change button name
            app.RunOptimizationButton.Text = 'Run Optimization';
            
            % UPDATE TABLE
            app.UITable.Data = array2table(app.finalTable);
            
            % DRAW THE BAR CHART
            data = app.finalTable(:,7:9);
            configName = app.finalTable(:,1:2);
            category =  cell(1,length(configName));
            for i = 1:length(configName)
                category{i} = ['f1','s',num2str(configName(i,1)),'e',num2str(configName(i,2))];
            end
            CAT = categorical(category);
            bar(app.UIAxes,CAT,data,0.4,'stacked');
%             app.UIAxes.XLabel = 'Config Names';
%             app.UIAxes.YLabel = 'Treatment Cost';
            legend(app.UIAxes,{'Membrane Cost','Energy Cost','Compressor Cost'},'Location', 'bestoutside');

        end

        % Menu selected function: ExportDataMenu
        function ExportDataMenuSelected(app, event)
            export = app.UITable.Data;
            header = app.UITable.ColumnName;
            export.Properties.VariableNames = header;
            fileName = 'Result.xls';
            c = clock;
            casenames = app.CaseNameEditField.Value;
            sheetnames = casenames(1:(length(casenames)>11)*11 + (length(casenames)<=11)*length(casenames));
            sheetName = [num2str(c(1)),num2str(c(2)),num2str(c(3)),'_',num2str(app.alpha),',',num2str(app.Permeability),'_',num2str(app.xF),'_',sheetnames];
            writetable(export,fileName,'Sheet',sheetName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FeedandrequirementPanel = uipanel(app.UIFigure);
            app.FeedandrequirementPanel.Title = 'Feed and requirement';
            app.Image = uiimage(app.FeedandrequirementPanel);
            app.Image.ImageSource = 'GeneralProcessScheme.jpg.png';
            app.xFEditFieldLabel = uilabel(app.FeedandrequirementPanel);
            app.xFEditFieldLabel.Text = 'xF';
            app.xFEditField = uieditfield(app.FeedandrequirementPanel, 'numeric');
            app.xFEditField.Limits = [0 1];
            app.xFEditField.Value = 0.5;
            app.yPEditFieldLabel = uilabel(app.FeedandrequirementPanel);
            app.yPEditFieldLabel.Text = 'yP';
            app.yPEditField = uieditfield(app.FeedandrequirementPanel, 'numeric');
            app.yPEditField.Limits = [0 1];
            app.yPEditField.Value = 0.9;
            app.xREditFieldLabel = uilabel(app.FeedandrequirementPanel);
            app.xREditFieldLabel.Text = 'xR';
            app.xREditField = uieditfield(app.FeedandrequirementPanel, 'numeric');
            app.xREditField.Limits = [0 1];
            app.xREditField.Value = 0.1;
            app.Nm3hLabel = uilabel(app.FeedandrequirementPanel);
            app.Nm3hLabel.Text = '1000 Nm3/h';
            app.OptimizationParameterPanel = uipanel(app.UIFigure);
            app.OptimizationParameterPanel.Title = 'Optimization Parameter';
            app.MaxPressureEditFieldLabel = uilabel(app.OptimizationParameterPanel);
            app.MaxPressureEditFieldLabel.Text = 'Max Pressure';
            app.MaxPressureEditField = uieditfield(app.OptimizationParameterPanel, 'numeric');
            app.MaxPressureEditField.Limits = [0 Inf];
            app.MaxPressureEditField.Value = 60;
            app.MaxStageNumberEditFieldLabel = uilabel(app.OptimizationParameterPanel);
            app.MaxStageNumberEditFieldLabel.Text = 'Max Stage Number';
            app.MaxStageNumberEditField = uieditfield(app.OptimizationParameterPanel, 'numeric');
            app.MaxStageNumberEditField.Limits = [2 Inf];
            app.MaxStageNumberEditField.ValueChangedFcn = createCallbackFcn(app, @MaxStageNumberEditFieldValueChanged, true);
            app.MaxStageNumberEditField.Value = 2;
            app.SingleStageLabel = uilabel(app.OptimizationParameterPanel);
            app.SingleStageLabel.Text = 'Single Stage';
            app.kEditFieldLabel = uilabel(app.OptimizationParameterPanel);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.OptimizationParameterPanel, 'numeric');
            app.kEditField.Limits = [0 Inf];
            app.kEditField.Value = 5369140;
            app.nEditField_2Label = uilabel(app.OptimizationParameterPanel);
            app.nEditField_2Label.Text = 'n';
            app.nEditField = uieditfield(app.OptimizationParameterPanel, 'numeric');
            app.nEditField.Limits = [-Inf 0];
            app.nEditField.Value = -2.636;
            app.MultistageLabel = uilabel(app.OptimizationParameterPanel);
            app.MultistageLabel.Text = 'Multistage';
            app.SelectivityEditFieldLabel = uilabel(app.OptimizationParameterPanel);
            app.SelectivityEditFieldLabel.Text = 'Selectivity ';
            app.SelectivityEditField = uieditfield(app.OptimizationParameterPanel, 'numeric');
            app.SelectivityEditField.Limits = [1.1 Inf];
            app.SelectivityEditField.Value = 8.5;
            app.PermeabilitybarrerEditFieldLabel = uilabel(app.OptimizationParameterPanel);
            app.PermeabilitybarrerEditFieldLabel.Text = 'Permeability (barrer)';
            app.PermeabilitybarrerEditField = uieditfield(app.OptimizationParameterPanel, 'numeric');
            app.PermeabilitybarrerEditField.Limits = [0 Inf];
            app.PermeabilitybarrerEditField.Value = 3900;
            app.Image2 = uiimage(app.OptimizationParameterPanel);
            app.Image2.ImageSource = 'Eqn.png';
            app.ProgressPanel = uipanel(app.UIFigure);
            app.ProgressPanel.Title = 'Progress...';
            app.configLabel = uilabel(app.ProgressPanel);
            app.configLabel.Text = 'config';
            app.RunOptimizationButton = uibutton(app.UIFigure, 'push');
            app.RunOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizationButtonPushed, true);
            app.RunOptimizationButton.Text = 'Run Optimization';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.CaseNameEditFieldLabel = uilabel(app.UIFigure);
            app.CaseNameEditFieldLabel.Text = 'Case Name';
            app.CaseNameEditField = uieditfield(app.UIFigure, 'text');
            app.CaseNameEditField.Value = 'untitled';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Config Names')
            ylabel(app.UIAxes, 'Treatment Cost (cent EUR/Nm3)')
            zlabel(app.UIAxes, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            app.ExportDataMenu = uimenu(app.ContextMenu);
            app.ExportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDataMenuSelected, true);
            app.ExportDataMenu.Text = 'Export Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = memoptim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end